classdef SettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveSettingsButton              matlab.ui.control.Button
        PostProcessingfunctionsListBox  matlab.ui.control.ListBox
        PostProcessingfunctionsListBoxLabel  matlab.ui.control.Label
        forautoscaleLabel               matlab.ui.control.Label
        ScaleSize                       matlab.ui.control.NumericEditField
        ScaleSizeEditFieldLabel         matlab.ui.control.Label
        PreProcessingfunctionsListBox   matlab.ui.control.ListBox
        PreProcessingfunctionsListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % The instance of RealTimeEEGUI used to create the settings dialog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGGUIapp)
            app.CallingApp = EEGGUIapp;
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.CallingApp.ScaleSize = app.ScaleSize.Value;
            fprintf('Scale Size = %f \n', app.ScaleSize.Value)
            display('Settings Saved!')
        end

        % Value changed function: ScaleSize
        function ScaleSizeValueChanged(app, event)
            value = app.ScaleSize.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreProcessingfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.PreProcessingfunctionsListBoxLabel.Text = 'Pre-Processing functions';
            app.PreProcessingfunctionsListBox = uilistbox(app.UIFigure);
            app.ScaleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleSizeEditFieldLabel.Text = 'Scale Size';
            app.ScaleSize = uieditfield(app.UIFigure, 'numeric');
            app.ScaleSize.ValueChangedFcn = createCallbackFcn(app, @ScaleSizeValueChanged, true);
            app.forautoscaleLabel = uilabel(app.UIFigure);
            app.forautoscaleLabel.Text = '(0 for auto-scale)';
            app.PostProcessingfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.PostProcessingfunctionsListBoxLabel.Text = 'Post-Processing functions';
            app.PostProcessingfunctionsListBox = uilistbox(app.UIFigure);
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end